classdef PulseGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EdgeKnobLabel                matlab.ui.control.Label
        EdgeKnob                     matlab.ui.control.Knob
        WindowKnobLabel              matlab.ui.control.Label
        WindowKnob                   matlab.ui.control.Knob
        LowPassKnobLabel             matlab.ui.control.Label
        LowPassKnob                  matlab.ui.control.Knob
        HighPassKnobLabel            matlab.ui.control.Label
        HighPassKnob                 matlab.ui.control.Knob
        ModulationKnobLabel          matlab.ui.control.Label
        ModulationKnob               matlab.ui.control.DiscreteKnob
        DispersionKnobLabel          matlab.ui.control.Label
        DispersionKnob               matlab.ui.control.DiscreteKnob
        PulsePlotUIAxes              matlab.ui.control.UIAxes
        Label                        matlab.ui.control.Label
        PlotTypeSwitch               matlab.ui.control.Switch
        AutoUpdateSwitchLabel        matlab.ui.control.Label
        AutoUpdateSwitch             matlab.ui.control.Switch
        Label_2                      matlab.ui.control.Label
        AutoUpdateLamp               matlab.ui.control.Lamp
        PlotButton                   matlab.ui.control.Button
        FrequencyEditFieldLabel      matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        SignalLengthsEditFieldLabel  matlab.ui.control.Label
        SignalLengthsEditField       matlab.ui.control.NumericEditField
        TypeDropDownLabel            matlab.ui.control.Label
        TypeDropDown                 matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        autoUpdate
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            
            signalLength = app.SignalLengthsEditField.Value ;
            frequency = app.FrequencyEditField.Value ;
            plotType = app.PlotTypeSwitch.Value ;
            
            p = generatePulse(app) ;
            t = -signalLength/2:1/frequency:signalLength/2;
            
            if strcmp(plotType,'Pulse')
                plot(app.PulsePlotUIAxes, t, p);
                xlabel(app.PulsePlotUIAxes,'Time(s)');
            else
                lp=length(p);
                Y=fft(p);
                sig=abs(Y(1:ceil(lp/2)));
                f=linspace(0, frequency/2, ceil(lp/2));
                plot(app.PulsePlotUIAxes, f(sig>1e-4), sig(sig>1e-4));
                % semilogy(app.PulsePlotUIAxes, f(sig>1e-4), sig(sig>1e-4));
                xlabel(app.PulsePlotUIAxes,'Frequency (Hz)');
            end            
            
        end
        
        function result = generatePulse(app)
            
            frequency = app.FrequencyEditField.Value ;
            signalLength = app.SignalLengthsEditField.Value ;
            edge = app.EdgeKnob.Value ;
            window = app.WindowKnob.Value ;
            modulation = str2num(app.ModulationKnob.Value) ;
            type = app.TypeDropDown.Value ;
            lowpass = app.LowPassKnob.Value ;
            highpass = app.HighPassKnob.Value ;
            dispersion = str2num(app.DispersionKnob.Value) ;
            
            startFrequency = 10 ;
            stopFrequency = 20 ;
            
            t = -signalLength/2:1/frequency:signalLength/2 ;
            sig = (signalLength/(8*edge))^2 ;
            
            switch app.TypeDropDown.Value
                
                case 'gaussian'
                    y = exp(-(t).^2/sig) ;
                case 'sinc'
                    x = 2*pi*edge*50.*t/(5*signalLength) ;
                    y = sin(x)./x ;
                    y(x==0) = 1 ;
                case 'square'
                    y = (t > -signalLength/edge/2) & (t < signalLength/edge/2) ;
                case 'triangle'
                    y = (t + signalLength/edge/2).*(t < 0) - (t - signalLength/edge/2).*(t >= 0);
                    y(y < 0) = 0 ;
                case 'monocycle'
                    if (sig == 0)
                        y = t ;
                    else
                        y = 2*t./sig.*exp(-(t).^2/sig) ;
                    end
                case 'exponential'
                    y = exp(-t*8*edge/signalLength) ;
                    y(t<0) = 0 ;
                case 'biexponential'
                    y = exp(-abs(t)*8*edge/signalLength) ;
                case 'mexican hat'
                    z = t./sqrt(0.75*sig);
                    y = sqrt(1/2*pi).*(1-z.^2).*exp(-z.^2/2) ;
                case 'raised cosine'
                    rb = 2*edge*50/(5*signalLength);
                    x = pi.*t.*rb ;
                    y = sin(x)./x ;
                    y(x==0) = 1 ;
                    y = y.*(cos(2*pi*rb.*t)./(1 - (4*rb.*t).^2));
                case 'double sinc'
                    x1 = 2*stopFrequency*pi.*t ;
                    x2 = 2*startFrequency*pi.*t ;
                    y1 = sin(x1)./x1 ;
                    y1(x1==0) = 1 ;
                    y2 = sin(x2)./x2 ;
                    y2(x2==0) = 1 ;
                    y = stopFrequency*y1 - startFrequency*y2;
                case 'sinc squared'
                    x = 2*pi*edge*16.*t/(5*signalLength) ;
                    y = sin(x)./x ;
                    y(x==0) = 1 ;
                    y = y.^2 ;
                case 'sweep'
                    theta = startFrequency.*(t + signalLength/2) + ...
                        ((stopFrequency - startFrequency)/(signalLength)).*(t+signalLength/2).^2;
                    y = real(exp(1j*(2*pi.*theta - pi/2)));
            end
            
            if (lowpass < 1) || (highpass > 0) || (dispersion ~= 0)
                c = length(y) ;
            end
            
            s = fft(y);
            sA = abs(s);
            sP = angle(s);
            if (lowpass < 1)
                cP = ceil(lowpass*c/2);
                if (cP == 0)
                    sA(:) = 0;
                else
                    sA(cP:end-cP+2) = 0;
                end
            end
            
            if (highpass > 0)
                cP = floor(highpass*c/2);
                if (cP ~= 0)
                    sA(1:cP) = 0;
                    sA(end-cP+2:end) = 0;
                end
            end
            
            if (dispersion ~= 0)
                pp = dispersion.*linspace(0,2*pi,c);
                sP = sP + pp;
            end
            s2 = sA.*cos(sP) + 1j*sA.*sin(sP);
            y = real(ifft(s2));
            
            if (window > 0)
                c = length(y);
                w = ones(size(y));
                p1 = floor(c*window/2);
                % Window is defined in three sections: taper, constant, taper
                w(1:p1+1) = (-cos((0:p1)/p1*pi)+1)/2;
                w(end-p1:end) = (cos((0:p1)/p1*pi)+1)/2;
                y=w.*y;
            end
            
            if modulation ~= 0
                y = y.*cos(pi*t*modulation);
            end
            result = y./max(abs(y)) ;
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AutoUpdateSwitch.Value = 'on';
            app.PlotTypeSwitch.Value = 'Pulse' ;
            app.PlotButton.Enable = 'off' ;
            app.autoUpdate = 1 ;
            updatePlot(app)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            updatePlot(app)
        end

        % Value changed function: AutoUpdateSwitch
        function AutoUpdateSwitchValueChanged(app, event)
            if strcmp(app.AutoUpdateSwitch.Value, 'on')
                app.autoUpdate = 1 ;
                app.PlotButton.Enable = 'off' ;
                app.AutoUpdateLamp.Color = [0 1 0] ;
            else
                app.autoUpdate = 0 ;
                app.PlotButton.Enable = 'on' ;
                app.AutoUpdateLamp.Color = [0.5 0.5 0.5] ;
            end        
        end

        % Value changed function: EdgeKnob
        function EdgeKnobValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: WindowKnob
        function WindowKnobValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: ModulationKnob
        function ModulationKnobValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: LowPassKnob
        function LowPassKnobValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: HighPassKnob
        function HighPassKnobValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: DispersionKnob
        function DispersionKnobValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: PlotTypeSwitch
        function PlotTypeSwitchValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Pulse Generator';
            app.EdgeKnobLabel = uilabel(app.UIFigure);
            app.EdgeKnobLabel.Text = 'Edge';
            app.EdgeKnob = uiknob(app.UIFigure, 'continuous');
            app.EdgeKnob.Limits = [0 4];
            app.EdgeKnob.ValueChangedFcn = createCallbackFcn(app, @EdgeKnobValueChanged, true);
            app.EdgeKnob.Value = 1;
            app.WindowKnobLabel = uilabel(app.UIFigure);
            app.WindowKnobLabel.Text = 'Window';
            app.WindowKnob = uiknob(app.UIFigure, 'continuous');
            app.WindowKnob.Limits = [0 1];
            app.WindowKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.WindowKnob.ValueChangedFcn = createCallbackFcn(app, @WindowKnobValueChanged, true);
            app.LowPassKnobLabel = uilabel(app.UIFigure);
            app.LowPassKnobLabel.Text = 'Low Pass';
            app.LowPassKnob = uiknob(app.UIFigure, 'continuous');
            app.LowPassKnob.Limits = [0 1];
            app.LowPassKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.LowPassKnob.ValueChangedFcn = createCallbackFcn(app, @LowPassKnobValueChanged, true);
            app.LowPassKnob.Value = 0.4;
            app.HighPassKnobLabel = uilabel(app.UIFigure);
            app.HighPassKnobLabel.Text = 'High Pass';
            app.HighPassKnob = uiknob(app.UIFigure, 'continuous');
            app.HighPassKnob.Limits = [0 1];
            app.HighPassKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.HighPassKnob.ValueChangedFcn = createCallbackFcn(app, @HighPassKnobValueChanged, true);
            app.ModulationKnobLabel = uilabel(app.UIFigure);
            app.ModulationKnobLabel.Text = 'Modulation';
            app.ModulationKnob = uiknob(app.UIFigure, 'discrete');
            app.ModulationKnob.Items = {'0', '50', '100', '150', '200', '250'};
            app.ModulationKnob.ValueChangedFcn = createCallbackFcn(app, @ModulationKnobValueChanged, true);
            app.ModulationKnob.Value = '0';
            app.DispersionKnobLabel = uilabel(app.UIFigure);
            app.DispersionKnobLabel.Text = 'Dispersion';
            app.DispersionKnob = uiknob(app.UIFigure, 'discrete');
            app.DispersionKnob.Items = {'-1.0', '-0.5', '0', '0.5', '1.0'};
            app.DispersionKnob.ValueChangedFcn = createCallbackFcn(app, @DispersionKnobValueChanged, true);
            app.DispersionKnob.Value = '0';
            app.PulsePlotUIAxes = uiaxes(app.UIFigure);
            xlabel(app.PulsePlotUIAxes, 'X')
            ylabel(app.PulsePlotUIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.PlotTypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.PlotTypeSwitch.Items = {'Pulse', 'Pulse Spectrum'};
            app.PlotTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotTypeSwitchValueChanged, true);
            app.PlotTypeSwitch.Value = 'Pulse Spectrum';
            app.AutoUpdateSwitchLabel = uilabel(app.UIFigure);
            app.AutoUpdateSwitchLabel.Text = 'Auto Update';
            app.AutoUpdateSwitch = uiswitch(app.UIFigure, 'slider');
            app.AutoUpdateSwitch.Items = {'off', 'on'};
            app.AutoUpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateSwitchValueChanged, true);
            app.AutoUpdateSwitch.Value = 'on';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.AutoUpdateLamp = uilamp(app.UIFigure);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Value = 500;
            app.SignalLengthsEditFieldLabel = uilabel(app.UIFigure);
            app.SignalLengthsEditFieldLabel.Text = 'Signal Length (s)';
            app.SignalLengthsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignalLengthsEditField.Limits = [1 Inf];
            app.SignalLengthsEditField.Value = 2;
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'gaussian', 'sinc', 'square', 'triangle', 'monocycle', 'exponential', 'biexponential', 'mexican hat', 'raised cosine', 'double sinc', 'sinc squared', 'sweep'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'gaussian';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PulseGenerator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end