classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        Plot1SwitchLabel  matlab.ui.control.Label
        Plot1Switch       matlab.ui.control.Switch
        LampLabel         matlab.ui.control.Label
        Lamp              matlab.ui.control.Lamp
        Lamp2Label        matlab.ui.control.Label
        Lamp2             matlab.ui.control.Lamp
        Plot2SwitchLabel  matlab.ui.control.Label
        Plot2Switch       matlab.ui.control.Switch
    end

    methods (Access = private)

        % Value changed function: Plot1Switch
        function Plot1SwitchValueChanged(app, event)
            value = app.Plot1Switch.Value;
            switch value
                case 'On'
                    x=linspace(-2*pi,2*pi);
                    y=sin(x);
                    plot(app.UIAxes,x,y);
                case 'Off'
                     cla (app.UIAxes, 'reset')


            end
        end

        % Value changed function: Plot2Switch
        function Plot2SwitchValueChanged(app, event)
            value = app.Plot2Switch.Value;
            switch value
                case 'On'
                    x=linspace(-2*pi,2*pi);
                    y=sin(x);
                    plot(app.UIAxes,x,y);
                case 'Off'
                     cla (app.UIAxes, 'reset')
             
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Plot1SwitchLabel = uilabel(app.UIFigure);
            app.Plot1SwitchLabel.Text = 'Plot 1';
            app.Plot1Switch = uiswitch(app.UIFigure, 'slider');
            app.Plot1Switch.ValueChangedFcn = createCallbackFcn(app, @Plot1SwitchValueChanged, true);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2Label = uilabel(app.UIFigure);
            app.Lamp2Label.Text = 'Lamp2';
            app.Lamp2 = uilamp(app.UIFigure);
            app.Plot2SwitchLabel = uilabel(app.UIFigure);
            app.Plot2SwitchLabel.Text = 'Plot 2';
            app.Plot2Switch = uiswitch(app.UIFigure, 'slider');
            app.Plot2Switch.ValueChangedFcn = createCallbackFcn(app, @Plot2SwitchValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end