classdef app_for_AM_FM_signals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        MATLABGUIFORAMandFMsignalsLabel  matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        ControlPanel                 matlab.ui.container.Panel
        ModulationtechniqueDropDownLabel  matlab.ui.control.Label
        ModulationtechniqueDropDown  matlab.ui.control.DropDown
        ModulatingSignalSwitchLabel  matlab.ui.control.Label
        ModulatingSignalSwitch       matlab.ui.control.Switch
        OutputSignalSwitchLabel      matlab.ui.control.Label
        OutputSignalSwitch           matlab.ui.control.Switch
    end

    methods (Access = private)

        % Value changed function: ModulationtechniqueDropDown
        function ModulationtechniqueDropDownValueChanged(app, event)
            value = app.ModulationtechniqueDropDown.Value;
            switch value
                case 'F.M'
                    %call
                    Am=5; % Amplitude of modulating signal
                    fa=2000; % Frequency of modulating signal
                    Ta=1/fa; % Time period of modulating signal
                    t=0:Ta/999:6*Ta; % Total time for simulation
                    ym=Am*sin(2*pi*fa*t); % Eqation of modulating signal
                case 'A.M'
                    %call
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Modulating Signal')
            xlabel(app.UIAxes, 'Time in seconds')
            ylabel(app.UIAxes, 'Amplitude in V')
            app.MATLABGUIFORAMandFMsignalsLabel = uilabel(app.UIFigure);
            app.MATLABGUIFORAMandFMsignalsLabel.Text = 'MATLAB G.U.I FOR AM and FM signals';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'AM/FM Output Signal')
            xlabel(app.UIAxes2, 'Time in Seconds')
            ylabel(app.UIAxes2, 'Amplitude in V')
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.ModulationtechniqueDropDownLabel = uilabel(app.ControlPanel);
            app.ModulationtechniqueDropDownLabel.Text = 'Modulation technique';
            app.ModulationtechniqueDropDown = uidropdown(app.ControlPanel);
            app.ModulationtechniqueDropDown.Items = {'F.M', 'A.M'};
            app.ModulationtechniqueDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationtechniqueDropDownValueChanged, true);
            app.ModulationtechniqueDropDown.Value = 'F.M';
            app.ModulatingSignalSwitchLabel = uilabel(app.ControlPanel);
            app.ModulatingSignalSwitchLabel.Text = 'Modulating Signal';
            app.ModulatingSignalSwitch = uiswitch(app.ControlPanel, 'slider');
            app.OutputSignalSwitchLabel = uilabel(app.ControlPanel);
            app.OutputSignalSwitchLabel.Text = 'Output Signal';
            app.OutputSignalSwitch = uiswitch(app.ControlPanel, 'slider');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_for_AM_FM_signals

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end