classdef trigonometric_hyperbolic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BasicTrigonometricDropDownLabel  matlab.ui.control.Label
        BasicTrigonometricDropDown     matlab.ui.control.DropDown
        BasicHyperbolicDropDownLabel   matlab.ui.control.Label
        BasicHyperbolicDropDown        matlab.ui.control.DropDown
        TrignonometricInverseCheckBox  matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
        PlotButton                     matlab.ui.control.Button
        PlotButton_2                   matlab.ui.control.Button
        HyperbolicInverseCheckBox      matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        Property % Description
        x=-5:1:5
    end


    methods (Access = private)

        % Value changed function: BasicTrigonometricDropDown
        function BasicTrigonometricDropDownValueChanged(app, event)
            value = app.BasicTrigonometricDropDown.Value;
            switch value
                case 'Sin x'
                    app.y=sin(app.x);
                case 'Cos x'
                    app.y=cos(app.x);
                case 'Tan x'
                    app.y=tan(app.x);
                case 'Sinh x'
                    app.y=sinh(app.x);
                case 'Cosh x'
                    app.y=cosh(app.x)
                case 'Tanh x'
                    app.y=tanh(app.x);
            end
        end

        % Value changed function: BasicHyperbolicDropDown
        function BasicHyperbolicDropDownValueChanged(app, event)
            value = app.BasicHyperbolicDropDown.Value;
            switch value
                case 'Sinh x'
                    app.y=sinh(app.x);
                case 'Cosh x'
                    app.y=cosh(app.x);
                case 'Tanh x'
                    app.y=tanh(app.x);
            end
        end

        % Value changed function: TrignonometricInverseCheckBox
        function TrignonometricInverseCheckBoxValueChanged(app, event)
            value = app.TrignonometricInverseCheckBox.Value;
            if value=='1'
                drop_value = app.BasicTrigonometricDropDown.Value;
                switch drop_value
                    case 'Sin x'
                        app.y=asin(app.x);
                    case 'Cos x'
                        app.y=acos(app.x);
                    case 'Tan x'
                        app.y=atan(app.x);
                    case 'Sinh x'
                        app.y=asinh(app.x);
                    case 'Cosh x'
                        app.y=acosh(app.x);
                    case 'Tanh x'
                        app.y=atanh(app.x);
                end
                
            end
            if value=='0'
            end
        end

        % Value changed function: HyperbolicInverseCheckBox
        function HyperbolicInverseCheckBoxValueChanged(app, event)
            value = app.HyperbolicInverseCheckBox.Value;
            if value=='1'
                
                switch drop_value2
                        case 'Sinh x'
                            app.y=asinh(app.x);
                        case 'Cosh x'
                            app.y=cosh(app.x);
                        case 'Tanh x'
                            app.y=atanh(app.x);
                end
       
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            v=app.TrignonometricInverseCheckBoxValueChanged();
            v2=app.BasicTrigonometricDropDownValueChanged();
            plot(app.UIAxes,app.x,v.y)
            plot(app.UIAxes,app.x,v2.y)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BasicTrigonometricDropDownLabel = uilabel(app.UIFigure);
            app.BasicTrigonometricDropDownLabel.Text = 'Basic Trigonometric';
            app.BasicTrigonometricDropDown = uidropdown(app.UIFigure);
            app.BasicTrigonometricDropDown.Items = {'Sin x', 'Cos x', 'Tan x', ' Sinh x', 'Cosh x', 'Tanh x'};
            app.BasicTrigonometricDropDown.ValueChangedFcn = createCallbackFcn(app, @BasicTrigonometricDropDownValueChanged, true);
            app.BasicTrigonometricDropDown.Value = 'Sin x';
            app.BasicHyperbolicDropDownLabel = uilabel(app.UIFigure);
            app.BasicHyperbolicDropDownLabel.Text = 'Basic Hyperbolic';
            app.BasicHyperbolicDropDown = uidropdown(app.UIFigure);
            app.BasicHyperbolicDropDown.Items = {'Sinh x', 'Cosh x', 'Tanh x'};
            app.BasicHyperbolicDropDown.ValueChangedFcn = createCallbackFcn(app, @BasicHyperbolicDropDownValueChanged, true);
            app.BasicHyperbolicDropDown.Value = 'Sinh x';
            app.TrignonometricInverseCheckBox = uicheckbox(app.UIFigure);
            app.TrignonometricInverseCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrignonometricInverseCheckBoxValueChanged, true);
            app.TrignonometricInverseCheckBox.Text = ' Trignonometric Inverse';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'TRIGONOMETRIC / HYPERBOLIC PLOT')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotButton_2.Text = 'Plot';
            app.HyperbolicInverseCheckBox = uicheckbox(app.UIFigure);
            app.HyperbolicInverseCheckBox.ValueChangedFcn = createCallbackFcn(app, @HyperbolicInverseCheckBoxValueChanged, true);
            app.HyperbolicInverseCheckBox.Text = ' Hyperbolic Inverse';
        end
    end

    methods (Access = public)

        % Construct app
        function app = trigonometric_hyperbolic_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end